classdef dirichlet_wave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GobackButton            matlab.ui.control.Button
        ValueSpinner            matlab.ui.control.Spinner
        ValueSpinnerLabel       matlab.ui.control.Label
        TypeofwavesButtonGroup  matlab.ui.container.ButtonGroup
        DiscreteButton          matlab.ui.control.RadioButton
        ContinuousButton        matlab.ui.control.RadioButton
        endEditField            matlab.ui.control.NumericEditField
        endEditFieldLabel       matlab.ui.control.Label
        startEditField          matlab.ui.control.NumericEditField
        startEditFieldLabel     matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        Shifting                matlab.ui.control.UIAxes
        Scaling                 matlab.ui.control.UIAxes
        Reversing               matlab.ui.control.UIAxes
        Original                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, ValueSpinner
        function ButtonPushed(app, event)
            start_2 = app.startEditField.Value;
            %no need for offset
            end_point_2 = app.endEditField.Value;
            
            value_spinner = app.ValueSpinner.Value;
            
            x = linspace(start_2, end_point_2);
            d8 = diric(x,8);
            
           if app.TypeofwavesButtonGroup.SelectedObject.Text == "Continuous"
                plot(app.Original, -(x/pi), d8)
                plot(app.Shifting, (-(x/pi)+value_spinner),diric(x+value_spinner, 8))
                plot(app.Scaling, -(x/pi), diric((x * value_spinner), 8))
                plot(app.Reversing, (x/pi), -d8) %cos(x) cannot be reversed
 
            else
                stem(app.Original, -(x/pi), d8)
                stem(app.Shifting, (-(x/pi)+value_spinner),diric(x+value_spinner, 8))
                stem(app.Scaling, -(x/pi), diric((x * value_spinner), 8))
                stem(app.Reversing, (x/pi), -d8) %cos(x) cannot be reversed

           end
        end

        % Button pushed function: GobackButton
        function GobackButtonPushed(app, event)
            run MyProject.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiaxes(app.UIFigure);
            title(app.Original, 'Dirichlet wave')
            xlabel(app.Original, 'X')
            ylabel(app.Original, 'Y')
            zlabel(app.Original, 'Z')
            app.Reversing = uiaxes(app.UIFigure);
            title(app.Reversing, 'Reversing')
            xlabel(app.Reversing, 'X')
            ylabel(app.Reversing, 'Y')
            zlabel(app.Reversing, 'Z')
            app.Scaling = uiaxes(app.UIFigure);
            title(app.Scaling, 'Scaling')
            xlabel(app.Scaling, 'X')
            ylabel(app.Scaling, 'Y')
            zlabel(app.Scaling, 'Z')
            app.Shifting = uiaxes(app.UIFigure);
            title(app.Shifting, 'Shifting')
            xlabel(app.Shifting, 'X')
            ylabel(app.Shifting, 'Y')
            zlabel(app.Shifting, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.startEditFieldLabel = uilabel(app.UIFigure);
            app.startEditFieldLabel.Text = 'start';
            app.startEditField = uieditfield(app.UIFigure, 'numeric');
            app.endEditFieldLabel = uilabel(app.UIFigure);
            app.endEditFieldLabel.Text = 'end';
            app.endEditField = uieditfield(app.UIFigure, 'numeric');
            app.TypeofwavesButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeofwavesButtonGroup.Title = 'Type of waves';
            app.ContinuousButton = uiradiobutton(app.TypeofwavesButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.ContinuousButton.Value = true;
            app.DiscreteButton = uiradiobutton(app.TypeofwavesButtonGroup);
            app.DiscreteButton.Text = 'Discrete';
            app.ValueSpinnerLabel = uilabel(app.UIFigure);
            app.ValueSpinnerLabel.Text = 'Value';
            app.ValueSpinner = uispinner(app.UIFigure);
            app.ValueSpinner.ValueChangingFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.GobackButton = uibutton(app.UIFigure, 'push');
            app.GobackButton.ButtonPushedFcn = createCallbackFcn(app, @GobackButtonPushed, true);
            app.GobackButton.Text = 'Go back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dirichlet_wave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end